classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MainGridLayout            matlab.ui.container.GridLayout
        TabGroup                  matlab.ui.container.TabGroup
        Tab1                      matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        App1InputLabel            matlab.ui.control.Label
        App1InputImage            matlab.ui.control.Image
        App1OutputLabel           matlab.ui.control.Label
        App1OutputImage           matlab.ui.control.Image
        App1ControlPanel          matlab.ui.container.Panel
        OrderSlider               matlab.ui.control.Slider
        OrderSliderLabel          matlab.ui.control.Label
        CutoffSlider              matlab.ui.control.Slider
        CutoffSliderLabel         matlab.ui.control.Label
        FilterTypeDropDown        matlab.ui.control.DropDown
        FilterTypeDropDownLabel   matlab.ui.control.Label
        App1LoadPanel             matlab.ui.container.Panel
        App1FileName              matlab.ui.control.Label
        App1LoadFileButton        matlab.ui.control.Button
        App1FilterPassLabel       matlab.ui.control.Label
        App1FilterPassPanel       matlab.ui.container.ButtonGroup
        HighPassButton            matlab.ui.control.RadioButton
        LowPassButton             matlab.ui.control.RadioButton
        ContrastEnhancementContrastStretchingTab  matlab.ui.container.Tab
        Grid2                     matlab.ui.container.GridLayout
        App2InputHist             histogram
        App2OutputHist            histogram
        App2IPane                 matlab.ui.container.Panel
        App2InputLabel            matlab.ui.control.Label
        App2IImage                matlab.ui.control.Image
        App2SelectMode            matlab.ui.container.ButtonGroup
        App2ColorButton           matlab.ui.control.ToggleButton
        App2GrayscaleButton       matlab.ui.control.ToggleButton
        App2LoadFileButton        matlab.ui.control.Button
        App2FileName              matlab.ui.control.Label
        App2OPane                 matlab.ui.container.Panel
        App2ASlider               matlab.ui.control.Slider
        bLabel                    matlab.ui.control.Label
        App2BSlider               matlab.ui.control.Slider
        aLabel                    matlab.ui.control.Label
        App2OutputLabel           matlab.ui.control.Label
        App2OImage                matlab.ui.control.Image
        App2SaveFileButton        matlab.ui.control.Button
        HistogramEqualizationTab  matlab.ui.container.Tab
    end

    
    properties (Access = private)
        I = truecolorload('images\1strawberry.png');
        filename = "No File Selected"
        a2 = 0;
        b2 = 100;

        O = truecolorload('images\1strawberry.png');
        R = truecolorload('images\1strawberry.png');
        ref_filename = "No File Selected"
    end

    methods (Static)
    
        function result = rgbtogray(I)
            result = rgb2gray(I);
            result(:,:,2) = result(:,:,1);
            result(:,:,3) = result(:,:,1);
        end
        
    end
    
    methods (Access = private)
        
        function loadImage(app)
            % Load to I
            [file, path] = uigetfile('*');
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);
            app.filename = filepath;
            app.I = truecolorload(filepath);

            % Update image outputs
            app.updateBasedOnTab();
        end

        function saveImage(app)
            % Get file name
            [file, path] = uiputfile({ ...
                '*.bmp', 'BMP files (*.bmp)'; ...
                '*.gif', 'GIF files (*.gif)'; ...
                '*.jpg', 'JPEG files (*jpg)'; ...
                '*.png', 'PNG files (*.png)'; ...
                '*.tif', 'TIFF files (*tif)' ...
            });
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);

            imwrite(app.O, filepath);
        end
        
        function loadRefImage(app)
            % Load to R
            [file, path] = uigetfile('*');
            if file == 0
                return
            end
            filepath = fullfile(path, file);
            disp(filepath);
            app.ref_filename = filepath;
            app.R = truecolorload(filepath);

            % Update only if tab 4 is selected
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.HistogramSpecificationTab
                app.updateApp4Values();
            end
        end

        function updateApp1Values(app)
            selectedButton = app.App1SelectMode.SelectedObject;
            if selectedButton.Text == "Grayscale"
                app.App1InputImage.ImageSource = app.rgbtogray(app.I);
                app.App1Hist.HistType = HistogramType.Grayscale;

                % Run function
                hist = histogramz(rgb2gray(app.I));
                app.App1Hist.Values1 = hist;
            else % selectedButton.Text == "Color"
                app.App1InputImage.ImageSource = app.I;
                app.App1Hist.HistType = HistogramType.Color;

                % Run function
                app.App1Hist.Values1 = histogramz(app.I(:,:,1));
                app.App1Hist.Values2 = histogramz(app.I(:,:,2));
                app.App1Hist.Values3 = histogramz(app.I(:,:,3));
            end
            app.App1Hist.refresh();
            app.App1FileName.Text = app.filename;
        end

        function updateApp2Values(app)
            selectedButton = app.App2SelectMode.SelectedObject;
            if selectedButton.Text == "Grayscale"
                app.App2IImage.ImageSource = app.rgbtogray(app.I);
                app.App2InputHist.HistType = HistogramType.Grayscale;
                app.App2OutputHist.HistType = HistogramType.Grayscale;

                % Run function
                a = app.a2 / 100;
                b = app.b2 / 100;
                app.O = contrastStretching(rgb2gray(app.I), a, b);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                hist = histogramz(rgb2gray(app.I));
                app.App2InputHist.Values1 = hist;
                hist = histogramz(app.O);
                app.App2OutputHist.Values1 = hist;

                % Display Image
                app.O(:,:,2) = app.O(:,:,1);
                app.O(:,:,3) = app.O(:,:,1);
                app.App2OImage.ImageSource = app.O;
            else % selectedButton.Text == "Color"
                app.App2IImage.ImageSource = app.I;
                app.App2InputHist.HistType = HistogramType.Color;
                app.App2OutputHist.HistType = HistogramType.Color;

                % Run function
                a = app.a2 / 100;
                b = app.b2 / 100;
                app.O = contrastStretching(app.I(:,:,1), a, b);
                app.O(:,:,2) = contrastStretching(app.I(:,:,2), a, b);
                app.O(:,:,3) = contrastStretching(app.I(:,:,3), a, b);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                app.App2InputHist.Values1 = histogramz(app.I(:,:,1));
                app.App2InputHist.Values2 = histogramz(app.I(:,:,2));
                app.App2InputHist.Values3 = histogramz(app.I(:,:,3));
                app.App2OutputHist.Values1 = histogramz(app.O(:,:,1));
                app.App2OutputHist.Values2 = histogramz(app.O(:,:,2));
                app.App2OutputHist.Values3 = histogramz(app.O(:,:,3));

                % Display Image
                app.App2OImage.ImageSource = app.O;
            end
            app.App2InputHist.refresh();
            app.App2OutputHist.refresh();
            app.App2FileName.Text = app.filename;
        end

        function updateApp3Values(app)
            selectedButton = app.App3SelectMode.SelectedObject;
            if selectedButton.Text == "Grayscale"
                app.App3IImage.ImageSource = app.rgbtogray(app.I);
                app.App3InputHist.HistType = HistogramType.Grayscale;
                app.App3OutputHist.HistType = HistogramType.Grayscale;

                % Run function
                app.O = histogramEqualization(app.I);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                hist = histogramz(rgb2gray(app.I));
                app.App3InputHist.Values1 = hist;
                hist = histogramz(app.O);
                app.App3OutputHist.Values1 = hist;

                % Display Image
                app.O(:,:,2) = app.O(:,:,1);
                app.O(:,:,3) = app.O(:,:,1);
                app.App3OImage.ImageSource = app.O;
            else % selectedButton.Text == "Color"
                app.App3IImage.ImageSource = app.I;
                app.App3InputHist.HistType = HistogramType.Color;
                app.App3OutputHist.HistType = HistogramType.Color;

                % Run function
                app.O = histogramEqualization(app.I(:,:,1));
                app.O(:,:,2) = histogramEqualization(app.I(:,:,2));
                app.O(:,:,3) = histogramEqualization(app.I(:,:,3));
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                app.App3InputHist.Values1 = histogramz(app.I(:,:,1));
                app.App3InputHist.Values2 = histogramz(app.I(:,:,2));
                app.App3InputHist.Values3 = histogramz(app.I(:,:,3));
                app.App3OutputHist.Values1 = histogramz(app.O(:,:,1));
                app.App3OutputHist.Values2 = histogramz(app.O(:,:,2));
                app.App3OutputHist.Values3 = histogramz(app.O(:,:,3));

                % Display Image
                app.App3OImage.ImageSource = app.O;
            end
            app.App3InputHist.refresh();
            app.App3OutputHist.refresh();
            app.App3FileName.Text = app.filename;
        end

        function updateApp4Values(app)
            selectedButton = app.App4SelectMode.SelectedObject;
            if selectedButton.Text == "Grayscale"
                app.App4IImage.ImageSource = app.rgbtogray(app.I);
                app.App4IImageRef.ImageSource = app.rgbtogray(app.R);
                app.App4InputHist.HistType = HistogramType.Grayscale;
                app.App4OutputHist.HistType = HistogramType.Grayscale;

                % Run function
                hist = histogramz(rgb2gray(app.R));
                app.O = histogramSpecification(rgb2gray(app.I), hist);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                app.App4InputHist.Values1 = hist;
                hist = histogramz(app.O);
                app.App4OutputHist.Values1 = hist;

                % Display Image
                app.O(:,:,2) = app.O(:,:,1);
                app.O(:,:,3) = app.O(:,:,1);
                app.App4OImage.ImageSource = app.O;
            else % selectedButton.Text == "Color"
                app.App4IImage.ImageSource = app.I;
                app.App4IImageRef.ImageSource = app.R;
                app.App4InputHist.HistType = HistogramType.Color;
                app.App4OutputHist.HistType = HistogramType.Color;

                % Run function
                histR = histogramz(app.R(:,:,1));
                histG = histogramz(app.R(:,:,2));
                histB = histogramz(app.R(:,:,3));
                app.O = histogramSpecification(app.I(:,:,1), histR);
                app.O(:,:,2) = histogramSpecification(app.I(:,:,2), histG);
                app.O(:,:,3) = histogramSpecification(app.I(:,:,3), histB);
                app.O = cast(app.O, 'uint8');

                % Calc Hist
                app.App4InputHist.Values1 = histR;
                app.App4InputHist.Values2 = histG;
                app.App4InputHist.Values3 = histB;
                app.App4OutputHist.Values1 = histogramz(app.O(:,:,1));
                app.App4OutputHist.Values2 = histogramz(app.O(:,:,2));
                app.App4OutputHist.Values3 = histogramz(app.O(:,:,3));

                % Display Image
                app.App4OImage.ImageSource = app.O;
            end
            app.App4InputHist.refresh();
            app.App4OutputHist.refresh();
            app.App4FileName.Text = app.filename;
            app.App4FileNameRef.Text = app.ref_filename;
        end
        
        function updateBasedOnTab(app)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.Tab1
                app.updateApp1Values();
            elseif selectedTab == app.ContrastEnhancementContrastStretchingTab
                app.updateApp2Values();
            elseif selectedTab == app.HistogramEqualizationTab
                app.updateApp3Values();
            elseif selectedTab == app.HistogramSpecificationTab
                app.updateApp4Values();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateBasedOnTab();
        end

        % Button pushed function: App1LoadFileButton
        function App1LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Callback function
        function App1SelectModeSelectionChanged(app, event)
            app.updateApp1Values();
        end

        % Button pushed function: App2LoadFileButton
        function App2LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Button pushed function: App2SaveFileButton
        function App2SaveFileButtonPushed(app, event)
            app.saveImage();
        end

        % Selection changed function: App2SelectMode
        function App2SelectModeSelectionChanged(app, event)
            app.updateApp2Values();
        end

        % Value changing function: App2ASlider
        function App2ASliderValueChanging(app, event)
            changingValue = event.Value;
            
            if changingValue >= app.b2
                app.b2 = changingValue;
                app.App2BSlider.Value = changingValue;
            end

            app.a2 = changingValue;
        end

        % Value changed function: App2ASlider
        function App2ASliderValueChanged(app, event)
            app.updateApp2Values();
        end

        % Value changing function: App2BSlider
        function App2BSliderValueChanging(app, event)
            changingValue = event.Value;
            
            if changingValue <= app.a2
                app.a2 = changingValue;
                app.App2ASlider.Value = changingValue;
            end

            app.b2 = changingValue;
        end

        % Value changed function: App2BSlider
        function App2BSliderValueChanged(app, event)
            app.updateApp2Values();
        end

        % Callback function
        function App3LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Callback function
        function App3SaveFileButtonPushed(app, event)
            app.saveImage();
        end

        % Callback function
        function App3SelectModeSelectionChanged(app, event)
            app.updateApp3Values();
        end

        % Callback function
        function App4LoadFileButtonPushed(app, event)
            app.loadImage();
        end

        % Callback function
        function App4LoadFileButtonRefPushed(app, event)
            app.loadRefImage();
        end

        % Callback function
        function App4SaveFileButtonPushed(app, event)
            app.saveImage();
        end

        % Callback function
        function App4SelectModeSelectionChanged(app, event)
            app.updateApp4Values();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.MainGridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Low/High Pass Filter';
            app.GridLayout = uigridlayout(app.Tab1);
            app.App1ControlPanel = uipanel(app.GridLayout);
            app.App1FilterPassPanel = uibuttongroup(app.App1ControlPanel);
            app.LowPassButton = uiradiobutton(app.App1FilterPassPanel);
            app.LowPassButton.Text = 'Low Pass';
            app.LowPassButton.Value = true;
            app.HighPassButton = uiradiobutton(app.App1FilterPassPanel);
            app.HighPassButton.Text = 'High Pass';
            app.App1FilterPassLabel = uilabel(app.App1ControlPanel);
            app.App1FilterPassLabel.Text = '  Filter Pass ';
            app.App1LoadPanel = uipanel(app.App1ControlPanel);
            app.App1LoadFileButton = uibutton(app.App1LoadPanel, 'push');
            app.App1LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @App1LoadFileButtonPushed, true);
            app.App1LoadFileButton.Text = 'Load File';
            app.App1FileName = uilabel(app.App1LoadPanel);
            app.App1FileName.Text = 'No File Selected';
            app.FilterTypeDropDownLabel = uilabel(app.App1ControlPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.App1ControlPanel);
            app.FilterTypeDropDown.Items = {'Ideal', 'Butterworth', 'Gaussian'};
            app.FilterTypeDropDown.Value = 'Ideal';
            app.CutoffSliderLabel = uilabel(app.App1ControlPanel);
            app.CutoffSliderLabel.Text = 'Cutoff';
            app.CutoffSlider = uislider(app.App1ControlPanel);
            app.OrderSliderLabel = uilabel(app.App1ControlPanel);
            app.OrderSliderLabel.Enable = 'off';
            app.OrderSliderLabel.Text = 'Order';
            app.OrderSlider = uislider(app.App1ControlPanel);
            app.OrderSlider.Limits = [0 10];
            app.OrderSlider.Enable = 'off';
            app.App1OutputImage = uiimage(app.GridLayout);
            app.App1OutputImage.ImageSource = '1strawberry.png';
            app.App1OutputLabel = uilabel(app.GridLayout);
            app.App1OutputLabel.Text = 'Output';
            app.App1InputImage = uiimage(app.GridLayout);
            app.App1InputImage.ImageSource = '1strawberry.png';
            app.App1InputLabel = uilabel(app.GridLayout);
            app.App1InputLabel.Text = 'Input';
            app.ContrastEnhancementContrastStretchingTab = uitab(app.TabGroup);
            app.ContrastEnhancementContrastStretchingTab.Title = 'Contrast Enhancement (Contrast Stretching)';
            app.Grid2 = uigridlayout(app.ContrastEnhancementContrastStretchingTab);
            app.App2OPane = uipanel(app.Grid2);
            app.App2SaveFileButton = uibutton(app.App2OPane, 'push');
            app.App2SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @App2SaveFileButtonPushed, true);
            app.App2SaveFileButton.Text = 'Save File';
            app.App2OImage = uiimage(app.App2OPane);
            app.App2OImage.ImageSource = '1strawberry.png';
            app.App2OutputLabel = uilabel(app.App2OPane);
            app.App2OutputLabel.Text = 'Output';
            app.aLabel = uilabel(app.App2OPane);
            app.aLabel.Text = 'a%';
            app.App2BSlider = uislider(app.App2OPane);
            app.App2BSlider.ValueChangedFcn = createCallbackFcn(app, @App2BSliderValueChanged, true);
            app.App2BSlider.ValueChangingFcn = createCallbackFcn(app, @App2BSliderValueChanging, true);
            app.App2BSlider.Value = 100;
            app.bLabel = uilabel(app.App2OPane);
            app.bLabel.Text = 'b%';
            app.App2ASlider = uislider(app.App2OPane);
            app.App2ASlider.ValueChangedFcn = createCallbackFcn(app, @App2ASliderValueChanged, true);
            app.App2ASlider.ValueChangingFcn = createCallbackFcn(app, @App2ASliderValueChanging, true);
            app.App2IPane = uipanel(app.Grid2);
            app.App2FileName = uilabel(app.App2IPane);
            app.App2FileName.Text = 'No File Selected';
            app.App2LoadFileButton = uibutton(app.App2IPane, 'push');
            app.App2LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @App2LoadFileButtonPushed, true);
            app.App2LoadFileButton.Text = 'Load File';
            app.App2SelectMode = uibuttongroup(app.App2IPane);
            app.App2SelectMode.SelectionChangedFcn = createCallbackFcn(app, @App2SelectModeSelectionChanged, true);
            app.App2GrayscaleButton = uitogglebutton(app.App2SelectMode);
            app.App2GrayscaleButton.Text = 'Color';
            app.App2GrayscaleButton.Value = true;
            app.App2ColorButton = uitogglebutton(app.App2SelectMode);
            app.App2ColorButton.Text = 'Grayscale';
            app.App2IImage = uiimage(app.App2IPane);
            app.App2IImage.ImageSource = '1strawberry.png';
            app.App2InputLabel = uilabel(app.App2IPane);
            app.App2InputLabel.Text = 'Input';
            app.App2OutputHist = histogram(app.Grid2);
            app.App2InputHist = histogram(app.Grid2);
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end